classdef ReviewOptodeIDs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReviewOptodeIdentitiesUIFigure  matlab.ui.Figure
        SaveButton                      matlab.ui.control.Button
        RegisterButton                  matlab.ui.control.Button
        RealignButton                   matlab.ui.control.Button
        UIAxes_modl                     matlab.ui.control.UIAxes
        UIAxes_tmpl                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        appTitle
        axesTitle
        
        % function handle to return property values on figure CloseRequest
        propReturnFnc = @()disp([])

    end
    
    properties (SetAccess = private)
        % Mx3 array containing template optode coordinates
        tmpl_optodePositions (:,3) double % = struct('xyz',[],'ID',[],'normals',[])
        % cellstring of template optode IDs
        tmpl_optodeIDs (:,1) cellstr = cell(0,1)
        
        % Mx3 or Mx3x2 array containing model optode coordinates
        % (projections and origins)
        modl_optodePositions (:,3,:) double % = struct('xyz',[],'ID',[],'normals',[])
        % cellstring of model optode IDs (can be assigned from the GUI)
        modl_optodeIDs (:,1) cellstr = cell(0,1)
        
        % struct containing template head surface ('faces', 'vertices' [, 'color'])
        tmpl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])
        
        % struct containing model head surface ('faces', 'vertices' [, 'color'])
        modl_headSurface (1,1) struct = struct('faces',[],'vertices',[],'color',[])
    end
    
    
    properties (Access = private)
        optMarkerContextMenu (1,1) matlab.ui.container.ContextMenu %= uicontextmenu()
        lastSelectedTmplOpt (1,1) matlab.graphics.chart.primitive.Line
    end


    %% ###################### SET METHODS ############################
    
    
    methods 
        
        function set.tmpl_optodePositions(app,val)
            app.tmpl_optodePositions = val;
%             app.plotOptPositions('tmpl');
        end
        
        function set.tmpl_optodeIDs(app,val)
            delete(app.optMarkerContextMenu.Children);
            detM = uimenu(app.optMarkerContextMenu,'Text','Detectors');
            srcM = uimenu(app.optMarkerContextMenu,'Text','Sources');
            optIDs = sort(val);
            for k = 1:numel(optIDs)
                if startsWith(optIDs{k},'S','IgnoreCase',true)
                    uimenu(detM,'Text',optIDs{k},'MenuSelectedFcn',@(src,evt)app.cb_assignOptID(src,evt));
                elseif startsWith(optIDs{k},'D',"IgnoreCase",true)
                    uimenu(srcM,'Text',optIDs{k},'MenuSelectedFcn',@(src,evt)app.cb_assignOptID(src,evt));
                else
                    uimenu(app.optMarkerContextMenu,'Text',optIDs{k},'MenuSelectedFcn',@(src,evt)app.cb_assignOptID(src,evt));
                end
            end
            app.tmpl_optodeIDs = val;
        end
        
        function set.tmpl_headSurface(app,val)
            app.tmpl_headSurface = val;
            app.plotSurf('tmpl');
        end
        
%         function set.modl_optodePositions(app,val)
%             app.modl_optodePositions = val;
%             app.plotOptPositions('modl');
%         end
        
        function set.modl_headSurface(app,val)
            app.modl_headSurface = val;
            app.plotSurf('modl');
        end


    %% ###################### GET METHODS ############################


%         function val = get.optodeIDs(app)
%             if isfield(app.tmpl_optodePositions,'ID')
%                 val = app.tmpl_optodePositions.ID;
%             else
%                 val = [];
%             end
%         end
    end
    
    
    %% #################### METHODS public ################################
    
    
    methods (Access = public)
        
        function plotModlOptPositions(app)
            pos = app.modl_optodePositions;
            ids = app.modl_optodeIDs;
                    
            pltTag = 'modl_optPosMarker';
            txtTag = 'modl_optPosID';
            
            plotProps = {'LineStyle',           '-', ...
                         'LineWidth',           4, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'Color',               [.3 .3 .3 .5], ...
                         'ContextMenu',         app.optMarkerContextMenu, ...
                         'ButtonDownFcn',       @(src,evt)app.cb_assignOptID(src,evt)};
            textProps = {'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 txtTag};
                        
            for k = size(pos,1):-1:1
                ph(k) = plot3(app.UIAxes_modl, ...
                                  squeeze(pos(k,1,:)), ...
                                  squeeze(pos(k,2,:)), ...
                                  squeeze(pos(k,3,:)), ...
                            'Tag',              pltTag,...
                            'UserData',         k,...
                            plotProps{:});
                phT(k) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1,1), ...
                                  pos(k,2,1), ...
                                  pos(k,3,1), ...
                            'Tag',              pltTag,...
                            'UserData',         k,...
                            plotProps{:});
            end
            
            assert(size(pos,1)==numel(ids),'Number of model optode positions and model optode IDs do not match!');
            for k = 1:numel(ids)
                for m = 1:size(pos,3)
                    text(app.UIAxes_modl, ...
                        pos(k,1,m), pos(k,2,m), pos(k,3,m), ...
                        ids{k},...
                        'UserData',         k,...
                        textProps{:})
                end
                if startsWith(ids{k},'S')
                    ph(k).MarkerFaceColor = [255, 146, 112]./255;
                elseif startsWith(ids{k},'D')
                    ph(k).MarkerFaceColor = [121, 191, 252]./255;
                else
                    ph(k).MarkerFaceColor = [190, 190 ,190]./255;
                end
            end
        end
        
        
        % =================================================================
        
        
        function plotTmplOptPositions(app)
            pos = app.tmpl_optodePositions;
            ids = app.tmpl_optodeIDs;
            
            pltTag = 'tmpl_optPosMarker';
            txtTag = 'tmpl_optPosID';
            
            plotProps = {'LineStyle',           '-', ...
                         'LineWidth',           4, ...
                         'Marker',              'o', ...
                         'MarkerSize',          20, ...
                         'Color',               [.3 .3 .3 .5], ...
                         'ButtonDownFcn',       @(src,evt)app.cb_setLastSelTmplOpt(src,evt)};
            textProps = {'HorizontalAlignment', "center", ...
                         'VerticalAlignment',   "middle", ...
                         'PickableParts',       'none', ...
                         'HitTest',             'off', ...
                         'Tag',                 txtTag};
            
            for k = size(pos,1):-1:1
                ph(k) = plot3(app.UIAxes_tmpl, ...
                                  pos(k,1), ...
                                  pos(k,2), ...
                                  pos(k,3), ...
                            'Tag',              pltTag,...
                            'UserData',         k,...
                            plotProps{:});
            end
            
            assert(size(pos,1)==numel(ids),'Number of template optode positions and template optode IDs do not match!');
            for k = 1:numel(ids)
                text(app.UIAxes_tmpl, ...
                    pos(k,1), pos(k,2), pos(k,3), ...
                    ids{k},...
                    'UserData',         k,...
                    textProps{:})
                if startsWith(ids{k},'S')
                    ph(k).MarkerFaceColor = [255, 146, 112]./255;
                elseif startsWith(ids{k},'D')
                    ph(k).MarkerFaceColor = [121, 191, 252]./255;
                else
                    ph(k).MarkerFaceColor = [190, 190 ,190]./255;
                end
            end
        end
        
        
        % =================================================================
        
        
        function plotSurf(app,typ)
            typ = validatestring(typ,{'modl','tmpl'});
            ah = app.(sprintf('UIAxes_%s',typ));
            srf = app.(sprintf('%s_headSurface',typ));
            
            tag = sprintf('%s_headSurf',typ);
            
            srfProps = {'PickableParts','none','HitTest','off','Tag',tag};
            
            switch typ
                case 'modl', srfProps = [srfProps {'FaceAlpha',     .2}];
                case 'tmpl', srfProps = [srfProps {'FaceAlpha',     .6}];
                otherwise, error('BUG');
            end
            
            ph = patch(ah,'faces',srf.faces, 'vertices',srf.vertices, ...
                       'EdgeColor',             'none', ...
                       'AlphaDataMapping',      'none', ...
                       'FaceLighting',          'gouraud', ...
                       srfProps{:});
            
            if isfield(srf,'color') && ~isempty(srf.color)
                set(ph,'FaceVertexCData', srf.color,...
                    'FaceColor',     'interp');
            else
                set(ph,'FaceColor', [1 1 1] .* .7);
            end
            material(ph,'dull');
        end
         
        
        % =================================================================
       
        
        function updateAssignment(app)
            % update lines connecting matched optodes in the template axis
            
            [lia,locb] = ismember(app.modl_optodeIDs,app.tmpl_optodeIDs);
            % how to update only changed assignments? or need to delete
            % everything and recreate?
        end
    end
    
    
    %% #################### METHODS private ##########################
    
    
    methods (Access = private)
        
        function cb_assignOptID(app,src,evnt)
            beep;
        end
        
        function cb_setLastSelTmplOpt(app,src,evnt)
            app.lastSelectedTmplOpt = src;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            Link = linkprop([app.UIAxes_modl app.UIAxes_tmpl], ...
                {'CameraUpVector', 'CameraPosition', ...
                 'CameraTarget', 'XLim', 'YLim', 'ZLim'});
            setappdata(app.ReviewOptodeIdentitiesUIFigure, 'AxesLink', Link);
            axis([app.UIAxes_modl app.UIAxes_tmpl],'vis3d','equal');
            
            app.optMarkerContextMenu = uicontextmenu(app.ReviewOptodeIdentitiesUIFigure);
            
%             app.optMarkerContextMenu.Parent = app.ReviewOptodeIdentitiesUIFigure;
            
            nvain = numel(varargin);
            flag = 0;
            for k = 1:nvain
                if flag > 0, flag = flag-1; continue; end
                if isprop(app,varargin{k})
                    assert(k<nvain,'Missing value for input property ''%s''!',varargin{k});
                    app.(varargin{k}) = varargin{k+1};
                    flag = 1;
                    
                else, error('Unrecognized input at input %d',k);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReviewOptodeIdentitiesUIFigure = uifigure('Visible', 'off');
            app.ReviewOptodeIdentitiesUIFigure.Name = 'Review Optode Identities';
            app.UIAxes_tmpl = uiaxes(app.ReviewOptodeIdentitiesUIFigure);
            app.UIAxes_tmpl.XTick = [];
            app.UIAxes_tmpl.YTick = [];
            app.UIAxes_tmpl.ZTick = [];
            app.UIAxes_modl = uiaxes(app.ReviewOptodeIdentitiesUIFigure);
            app.UIAxes_modl.XTick = [];
            app.UIAxes_modl.YTick = [];
            app.UIAxes_modl.ZTick = [];
            app.RealignButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.RealignButton.Enable = 'off';
            app.RealignButton.Tooltip = {'Manually assign at least 3 Optode IDs using the context menu in the left panel to realign the optode positions to the template. Then run registration again.'};
            app.RealignButton.Text = 'Realign';
            app.RegisterButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Text = 'Register';
            app.SaveButton = uibutton(app.ReviewOptodeIdentitiesUIFigure, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.ReviewOptodeIdentitiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReviewOptodeIDs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReviewOptodeIdentitiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReviewOptodeIdentitiesUIFigure)
        end
    end
end